classdef MIX_PROPORTIONING_OF_CONCRETE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        antonyrajengggmailcomLabel      matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        Kgm3Label_13                    matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        ViscosityModifyingagentisnotconsideredinthisversionLabel  matlab.ui.control.Label
        Kgm3Label_12                    matlab.ui.control.Label
        Kgm3Label_11                    matlab.ui.control.Label
        FINALLabel                      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        AuthorsLabel                    matlab.ui.control.Label
        ValuesneedtobeenteredinthecolouredboxappropriatelyLabel  matlab.ui.control.Label
        AggregatesareconsideredtobeinsaturatedsurfacedryconditionLabel  matlab.ui.control.Label
        DesignedasperIS102622019andotherISrelaventcodesLabel  matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        Kgm3Label_10                    matlab.ui.control.Label
        Kgm3Label_9                     matlab.ui.control.Label
        Kgm3Label_8                     matlab.ui.control.Label
        Kgm3Label_7                     matlab.ui.control.Label
        Kgm3Label_6                     matlab.ui.control.Label
        Kgm3Label_5                     matlab.ui.control.Label
        Kgm3Label_4                     matlab.ui.control.Label
        Kgm3Label_3                     matlab.ui.control.Label
        Kgm3Label_2                     matlab.ui.control.Label
        Kgm3Label                       matlab.ui.control.Label
        WCmEditField                    matlab.ui.control.NumericEditField
        WCmEditFieldLabel               matlab.ui.control.Label
        ChemicalAdmixtureEditField      matlab.ui.control.NumericEditField
        ChemicalAdmixtureEditFieldLabel  matlab.ui.control.Label
        CoarseAggregateEditField        matlab.ui.control.NumericEditField
        CoarseAggregateEditFieldLabel   matlab.ui.control.Label
        FineAggregateEditField          matlab.ui.control.NumericEditField
        FineAggregateEditFieldLabel     matlab.ui.control.Label
        WaterEditField                  matlab.ui.control.NumericEditField
        WaterEditFieldLabel             matlab.ui.control.Label
        MetakaolinEditField             matlab.ui.control.NumericEditField
        MetakaolinEditFieldLabel        matlab.ui.control.Label
        GGBSEditField                   matlab.ui.control.NumericEditField
        GGBSEditFieldLabel              matlab.ui.control.Label
        SilicaFumeEditField             matlab.ui.control.NumericEditField
        SilicaFumeEditFieldLabel        matlab.ui.control.Label
        FlyAshEditField                 matlab.ui.control.NumericEditField
        FlyAshEditFieldLabel            matlab.ui.control.Label
        MIXCALCULATIONSBASEDONSSDCONDITIONLabel  matlab.ui.control.Label
        CementContentEditField          matlab.ui.control.NumericEditField
        CementContentEditFieldLabel     matlab.ui.control.Label
        SpecificGravityofFineAggregateEditField  matlab.ui.control.NumericEditField
        SpecificGravityofFineAggregateEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofCoarseAggregateEditField  matlab.ui.control.NumericEditField
        SpecificGravityofCoarseAggregateEditFieldLabel  matlab.ui.control.Label
        MassofCementitiousMaterialEditField  matlab.ui.control.NumericEditField
        MassofCementitiousMaterialLabel  matlab.ui.control.Label
        SpecificGravityofChemicalAdmixtureEditField  matlab.ui.control.NumericEditField
        SpecificGravityofChemicalAdmixtureEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofMetakaolinEditField  matlab.ui.control.NumericEditField
        SpecificGravityofMetakaolinEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofGGBSEditField  matlab.ui.control.NumericEditField
        SpecificGravityofGGBSEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofSilicaFumeEditField  matlab.ui.control.NumericEditField
        SpecificGravityofSilicaFumeEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofFlyashEditField  matlab.ui.control.NumericEditField
        SpecificGravityofFlyashEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofWaterEditField  matlab.ui.control.NumericEditField
        SpecificGravityofWaterEditFieldLabel  matlab.ui.control.Label
        SpecificGravityofCementEditField  matlab.ui.control.NumericEditField
        SpecificGravityofCementEditFieldLabel  matlab.ui.control.Label
        MassofChemicalAdmixtureEditField  matlab.ui.control.NumericEditField
        MassofChemicalAdmixtureEditFieldLabel  matlab.ui.control.Label
        MassofFineAggregateEditField    matlab.ui.control.NumericEditField
        MassofFineAggregateEditFieldLabel  matlab.ui.control.Label
        MassofCoarseAggregateEditField  matlab.ui.control.NumericEditField
        MassofCoarseAggregateEditFieldLabel  matlab.ui.control.Label
        VolumeofallinAggregateEditField  matlab.ui.control.NumericEditField
        VolumeofallinAggregateEditFieldLabel  matlab.ui.control.Label
        VolumeofChemicalAdmixtureEditField  matlab.ui.control.NumericEditField
        VolumeofChemicalAdmixtureEditFieldLabel  matlab.ui.control.Label
        VolumeofMetakaolinEditField     matlab.ui.control.NumericEditField
        VolumeofMetakaolinEditFieldLabel  matlab.ui.control.Label
        VolumeofGGBSEditField           matlab.ui.control.NumericEditField
        VolumeofGGBSEditFieldLabel      matlab.ui.control.Label
        VolumeofSilicaFumeEditField     matlab.ui.control.NumericEditField
        VolumeofSilicaFumeEditFieldLabel  matlab.ui.control.Label
        VolumeofFlyAshEditField         matlab.ui.control.NumericEditField
        VolumeofFlyAshEditFieldLabel    matlab.ui.control.Label
        VolumeofWaterEditField          matlab.ui.control.NumericEditField
        VolumeofWaterEditFieldLabel     matlab.ui.control.Label
        VolumeofCementEditField         matlab.ui.control.NumericEditField
        VolumeofCementEditFieldLabel    matlab.ui.control.Label
        VolumeofEntrappedAirEditField   matlab.ui.control.NumericEditField
        VolumeofEntrappedAirEditFieldLabel  matlab.ui.control.Label
        TotalVolumeEditField            matlab.ui.control.NumericEditField
        TotalVolumeEditFieldLabel       matlab.ui.control.Label
        VolumeoffineAggregateperunitvolumeofAggregateEditField  matlab.ui.control.NumericEditField
        VolumeoffineAggregateperunitvolumeofAggregateEditFieldLabel  matlab.ui.control.Label
        AdjustedVolumneofCoarseAggregatebasedonWCEditField  matlab.ui.control.NumericEditField
        AdjustedVolumneofCoarseAggregatebasedonWCEditFieldLabel  matlab.ui.control.Label
        VolumeofCoarseAggregateforCategory2EditField  matlab.ui.control.NumericEditField
        VolumeofCoarseAggregateforCategory2EditFieldLabel  matlab.ui.control.Label
        VolumeofCoarseAggregateforCategory1EditField  matlab.ui.control.NumericEditField
        VolumeofCoarseAggregateforCategory1EditFieldLabel  matlab.ui.control.Label
        MAEditField                     matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        BasedonExperienceDropDown       matlab.ui.control.DropDown
        BasedonExperienceLabel          matlab.ui.control.Label
        FinalRevisedCementContentEditField  matlab.ui.control.NumericEditField
        FinalRevisedCementContentEditFieldLabel  matlab.ui.control.Label
        MetakaolinContentEditField      matlab.ui.control.NumericEditField
        MetakaolinContentEditFieldLabel  matlab.ui.control.Label
        GGBSContentEditField            matlab.ui.control.NumericEditField
        GGBSContentEditFieldLabel       matlab.ui.control.Label
        FlyashContentEditField          matlab.ui.control.NumericEditField
        FlyashContentEditFieldLabel     matlab.ui.control.Label
        SilicaFumeContentEditField      matlab.ui.control.NumericEditField
        SilicaFumeContentEditFieldLabel  matlab.ui.control.Label
        CementitiousMaterialContentEditField  matlab.ui.control.NumericEditField
        CementitiousMaterialContentEditFieldLabel  matlab.ui.control.Label
        FinalCementContentEditField     matlab.ui.control.NumericEditField
        FinalCementContentEditFieldLabel  matlab.ui.control.Label
        AdjustedWaterContentbasedontheSuperplasticizerEditField  matlab.ui.control.NumericEditField
        AdjustedWaterContentbasedontheSuperplasticizerEditFieldLabel  matlab.ui.control.Label
        AdjustedWaterContentbasedonShapeofAggregateEditField  matlab.ui.control.NumericEditField
        AdjustedWaterContentbasedonShapeofAggregateEditFieldLabel  matlab.ui.control.Label
        InitialEstimatedWatercontentEditField  matlab.ui.control.NumericEditField
        InitialEstimatedWatercontentEditFieldLabel  matlab.ui.control.Label
        AdjustedWaterContentbasedonWorkabilityEditField  matlab.ui.control.NumericEditField
        AdjustedWaterContentbasedonWorkabilityEditFieldLabel  matlab.ui.control.Label
        MinimumGradeEditField           matlab.ui.control.NumericEditField
        MinimumGradeEditFieldLabel      matlab.ui.control.Label
        MinCementContentEditField       matlab.ui.control.NumericEditField
        MinCementContentEditFieldLabel  matlab.ui.control.Label
        TYPEOFCONCRETEDropDown          matlab.ui.control.DropDown
        TYPEOFCONCRETEDropDownLabel     matlab.ui.control.Label
        MAXWCEditField                  matlab.ui.control.NumericEditField
        MAXWCEditFieldLabel             matlab.ui.control.Label
        AdjustedWCratiobasedonExposureConditionsEditField  matlab.ui.control.NumericEditField
        AdjustedWCratiobasedonExposureConditionsEditFieldLabel  matlab.ui.control.Label
        FreeWaterCementRatioEditField   matlab.ui.control.NumericEditField
        FreeWaterCementRatioEditFieldLabel  matlab.ui.control.Label
        FOROPC33EditField               matlab.ui.control.NumericEditField
        FOROPC33EditFieldLabel          matlab.ui.control.Label
        FOROPC43EditField               matlab.ui.control.NumericEditField
        FOROPC43EditFieldLabel          matlab.ui.control.Label
        FOROPC53EditField               matlab.ui.control.NumericEditField
        FOROPC53EditFieldLabel          matlab.ui.control.Label
        MinimumGrade_RCEditField        matlab.ui.control.NumericEditField
        MinimumGrade_RCEditFieldLabel   matlab.ui.control.Label
        MaxWCRatio_RCEditField          matlab.ui.control.NumericEditField
        MaxWCRatio_RCEditFieldLabel     matlab.ui.control.Label
        MinCementContent_RCEditField    matlab.ui.control.NumericEditField
        MinCementContent_RCEditFieldLabel  matlab.ui.control.Label
        MinimumGrade_PlainEditField     matlab.ui.control.NumericEditField
        MinimumGrade_PlainEditFieldLabel  matlab.ui.control.Label
        MaxWCRatio_PlainEditField       matlab.ui.control.NumericEditField
        MaxWCRatio_PlainEditFieldLabel  matlab.ui.control.Label
        MinCementContent_PlainEditField  matlab.ui.control.NumericEditField
        MinCementContent_PlainEditFieldLabel  matlab.ui.control.Label
        ApproxFinalAirContentEditField  matlab.ui.control.NumericEditField
        ApproxFinalAirContentEditFieldLabel  matlab.ui.control.Label
        ApproxAircontentABOVEGRADEM65EditField  matlab.ui.control.NumericEditField
        ApproxAircontentABOVEGRADEM65EditFieldLabel  matlab.ui.control.Label
        ApproxAirContentGradeuptoM60EditField  matlab.ui.control.NumericEditField
        ApproxAirContentGradeuptoM60EditFieldLabel  matlab.ui.control.Label
        FINALTARGETSTRENGTHEditField    matlab.ui.control.NumericEditField
        FINALTARGETSTRENGTHEditFieldLabel  matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        TARGETSTRENGTHBASEDONFACTOREditField  matlab.ui.control.NumericEditField
        TARGETSTRENGTHBASEDONFACTORLabel  matlab.ui.control.Label
        StandardDeviationEditField      matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        TARGETSTRENGTHBASEDONSDEditField  matlab.ui.control.NumericEditField
        TARGETSTRENGTHBASEDONSDEditFieldLabel  matlab.ui.control.Label
        ZONEOFFINEAGGREGATEDropDown     matlab.ui.control.DropDown
        ZONEOFFINEAGGREGATEDropDownLabel  matlab.ui.control.Label
        VISCOSITYMODIFYINGAGENTDropDown  matlab.ui.control.DropDown
        VISCOSITYMODIFYINGAGENTDropDownLabel  matlab.ui.control.Label
        SUPERPLASTICIZERDropDown        matlab.ui.control.DropDown
        SUPERPLASTICIZERDropDownLabel   matlab.ui.control.Label
        MAXCEMENTCONTENTDropDown        matlab.ui.control.DropDown
        MAXCEMENTCONTENTDropDownLabel   matlab.ui.control.Label
        SHAPEOFAGGREGATEDropDown        matlab.ui.control.DropDown
        SHAPEOFAGGREGATEDropDownLabel   matlab.ui.control.Label
        DEGREEOFSUPERVISIONDropDown     matlab.ui.control.DropDown
        DEGREEOFSUPERVISIONDropDownLabel  matlab.ui.control.Label
        METHODOFCONCRETEPLACINGDropDown  matlab.ui.control.DropDown
        METHODOFCONCRETEPLACINGLabel    matlab.ui.control.Label
        WORKABILITYDropDown             matlab.ui.control.DropDown
        WORKABILITYDropDownLabel        matlab.ui.control.Label
        EXPOSURECONDITIONDropDown       matlab.ui.control.DropDown
        EXPOSURECONDITIONDropDownLabel  matlab.ui.control.Label
        MAXSIZECOARSEOFAGGREGATEDropDown  matlab.ui.control.DropDown
        MAXSIZEOFAGGREGATELabel         matlab.ui.control.Label
        TYPESOFMINERALADMIXTURESDropDown  matlab.ui.control.DropDown
        TYPESOFMINERALADMIXTURESLabel   matlab.ui.control.Label
        TYPEOFCEMENTDropDown            matlab.ui.control.DropDown
        TYPEOFCEMENTDropDownLabel       matlab.ui.control.Label
        GRADEOFCONCRETEDropDown         matlab.ui.control.DropDown
        GRADEOFCONCRETEDropDownLabel    matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        ForTargetStrengthUpto70MPaFromGraphLabel  matlab.ui.control.Label
        SelectionofWaterCementRatioLabel  matlab.ui.control.Label
        REINFORCEDCONCRETELabel         matlab.ui.control.Label
        PLAINCONCRETELabel              matlab.ui.control.Label
        MPaLabel                        matlab.ui.control.Label
        BasedonFactorforGradeofconcreteLabel  matlab.ui.control.Label
        BasedonStandardDeviationLabel   matlab.ui.control.Label
        RESULTSButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RESULTSButton
        function RESULTSButtonPushed(app, event)
    a=str2double(app.GRADEOFCONCRETEDropDown.Value);
    if a<=15
        b=3.5;
    elseif a<=25
        b=4;
    elseif a<=60
        b=5;
    elseif a<=80
        b=6;
    else
        b=NaN;
    end
    app.StandardDeviationEditField.Value=b;
    app.TARGETSTRENGTHBASEDONSDEditField.Value = a+1.65*app.StandardDeviationEditField.Value; 
    c=str2double(app.GRADEOFCONCRETEDropDown.Value);
    if c<=15
        d=5;
    elseif c<=25
        d=5.5;
    elseif c<=60
        d=6.5;
    elseif c<=80
        d=8;
    else
        d=NaN;
    end
    app.XEditField.Value=d;
    app.TARGETSTRENGTHBASEDONFACTOREditField.Value = c+d;
    app.FINALTARGETSTRENGTHEditField.Value=max(app.TARGETSTRENGTHBASEDONSDEditField.Value,app.TARGETSTRENGTHBASEDONFACTOREditField.Value);
    e=str2double(app.MAXSIZECOARSEOFAGGREGATEDropDown.Value);
    if e<=8
        f=1.875;
    elseif e<=10
        f=1.5;
    elseif e<=12.5
        f=1.25;
    elseif e<=20
        f=1;
    elseif e<=40
        f=0.8;
    else
        f=NaN;
    end
    app.ApproxAirContentGradeuptoM60EditField.Value=f;
    g=str2double(app.MAXSIZECOARSEOFAGGREGATEDropDown.Value);
    if g<=8
        h=1.25;
    elseif g<=10
        h=1;
    elseif g<=12.5
        h=0.8;
    elseif g<=20
        h=0.5;
    elseif g<=40
        h=0.25;
    else
        h=NaN;
    end
    app.ApproxAircontentABOVEGRADEM65EditField.Value=h;
    i=str2double(app.GRADEOFCONCRETEDropDown.Value);
    if i<=60
        j=app.ApproxAirContentGradeuptoM60EditField.Value;
    elseif i>60
        j=app.ApproxAircontentABOVEGRADEM65EditField.Value;
    end
    app.ApproxFinalAirContentEditField.Value=j;
    k=app.EXPOSURECONDITIONDropDown.Value;
    switch k
        case 'Mild'
            l=220;
        case 'Moderate'
            l=240;
        case 'Severe'
            l=250;
        case 'Very Severe'
            l=260;
        case 'Extreme'
            l=280;
        otherwise
            l=NaN;
    end
    app.MinCementContent_PlainEditField.Value=l;
    
    switch k
        case 'Mild'
            m=0.6;
        case 'Moderate'
            m=0.6;
        case 'Severe'
            m=0.5;
        case 'Very Severe'
            m=0.45;
        case 'Extreme'
            m=0.4;
        otherwise
            m=NaN;
    end
    app.MaxWCRatio_PlainEditField.Value=m;
    switch k
        case 'Mild'
            n=0;
        case 'Moderate'
            n=15;
        case 'Severe'
            n=20;
        case 'Very Severe'
            n=20;
        case 'Extreme'
            n=25;
        otherwise
            n=NaN;
    end
    app.MinimumGrade_PlainEditField.Value=n;
    o=app.EXPOSURECONDITIONDropDown.Value;
    switch o
        case 'Mild'
            p=300;
        case 'Moderate'
            p=300;
        case 'Severe'
            p=320;
        case 'Very Severe'
            p=340;
        case 'Extreme'
            p=360;
        otherwise
            p=NaN;
    end
    app.MinCementContent_RCEditField.Value=p;
    switch o
        case 'Mild'
            q=0.55;
        case 'Moderate'
            q=0.50;
        case 'Severe'
            q=0.45;
        case 'Very Severe'
            q=0.45;
        case 'Extreme'
            q=0.40;
        otherwise
            q=NaN;
    end
    app.MaxWCRatio_RCEditField.Value=q;
     switch o
        case 'Mild'
            r=20;
        case 'Moderate'
            r=25;
        case 'Severe'
            r=30;
        case 'Very Severe'
            r=35;
        case 'Extreme'
            r=40;
        otherwise
            r=NaN;
    end
    app.MinimumGrade_RCEditField.Value=r;
    s=app.FINALTARGETSTRENGTHEditField.Value;
   if s<=26.60
        t=0.623744;
    elseif s<=31.60
        t=0.55694;
    elseif s<=38.25
        t=0.487345;
    elseif s<=43.25
        t=0.44472;
    elseif s<=48.25
        t=0.399174;
     elseif s<=53.25
        t=0.364895;
     elseif s<=58.25
        t=0.335187;
     elseif s<=63.25
        t=0.301345;
      elseif s<=68.25
        t=0.276545;
    else
        t=NaN;
   end
   app.FOROPC53EditField.Value=t;
   v=app.FINALTARGETSTRENGTHEditField.Value;
   if v<=20.775
        u=0.614444;
   elseif v<=26.60
        u=0.53772;
    elseif v<=31.60
        u=0.478045;
    elseif v<=38.25
        u=0.422968;
    elseif v<=43.25
        u=0.37962;
    elseif v<=48.25
        u=0.345778;
     elseif v<=53.25
        u=0.31142;
     elseif v<=58.25
        u=0.286233;
     elseif v<=63.25
        u=0.25872;
    else
        u=NaN;
   end
   app.FOROPC43EditField.Value=u;
   if v<=15.775
        w=0.625294;
   elseif v<=20.775
        w=0.54857;
   elseif v<=26.60
        w=0.48037;
    elseif v<=31.60
        w=0.42922;
    elseif v<=38.25
        w=0.37807;
    elseif v<=43.25
        w=0.33777;
    elseif v<=48.25
        w=0.312195;
     elseif v<=53.25
        w=0.276545;
     elseif v<=58.25
        w=0.25097;
   else
        w=NaN;
  end
    app.FOROPC33EditField.Value=w;
    a1=app.TYPEOFCEMENTDropDown.Value;
    switch a1
        case 'OPC 33 grade conforming to IS 269-2015'
            a2=app.FOROPC33EditField.Value;
        case 'OPC 43 grade conforming to IS 269-2015'
            a2=app.FOROPC43EditField.Value;
        case 'OPC 53 grade conforming to IS 269-2015'
            a2=app.FOROPC53EditField.Value;
        case 'OPC 43S grade conforming to IS 269-2015'
            a2=app.FOROPC43EditField.Value;
        case 'OPC 53S grade conforming to IS 269-2015'
            a2=app.FOROPC53EditField.Value;
        case 'PPC-(Flyash Based) grade conforming to IS 1489-Part-I'
            a2=app.FOROPC43EditField.Value;
        case 'PPC(Calcined Clay Based) grade conforming to to IS 1489-Part-II'
            a2=app.FOROPC43EditField.Value;
        otherwise
            a2=NaN;
    end
    app.FreeWaterCementRatioEditField.Value=a2;
    a3=app.TYPEOFCONCRETEDropDown.Value;
    switch a3
        case 'PLAIN CONCRETE'
            a4=app.MaxWCRatio_PlainEditField.Value;
        case 'REINFORCED CONCRETE'
            a4=app.MaxWCRatio_RCEditField.Value; 
        otherwise
            a4=NaN;
    end
     app.MAXWCEditField.Value=a4;
    app.AdjustedWCratiobasedonExposureConditionsEditField.Value=min(app.FreeWaterCementRatioEditField.Value,app.MAXWCEditField.Value);
    a5=app.TYPEOFCONCRETEDropDown.Value;
    switch a5
        case 'PLAIN CONCRETE'
            a6=app.MinCementContent_PlainEditField.Value;
        case 'REINFORCED CONCRETE'
            a6=app.MinCementContent_RCEditField.Value; 
        otherwise
            a6=NaN;
    end
     app.MinCementContentEditField.Value=a6;
     a7=app.TYPEOFCONCRETEDropDown.Value;
    switch a7
        case 'PLAIN CONCRETE'
            a8=app.MinimumGrade_PlainEditField.Value;
        case 'REINFORCED CONCRETE'
            a8=app.MinimumGrade_RCEditField.Value; 
        otherwise
            a8=NaN;
    end
     app.MinimumGradeEditField.Value=a8;
     a9=str2double(app.MAXSIZECOARSEOFAGGREGATEDropDown.Value);
     if a9<=8
         a10=260;
     elseif a9<=10
         a10=208;
     elseif a9<=12.5
         a10=166.40;
      elseif a9<=20
         a10=186;
      elseif a9<=40
         a10=165;
      elseif a9<=80
         a10=145;
      elseif a9<=150
         a10=125;
     else
         a10=NaN;
     end
      app.InitialEstimatedWatercontentEditField.Value=a10;
      a11=str2double(app.WORKABILITYDropDown.Value);
      if a11<=25
          a12=a10-(a10*0.03);
      elseif a11<=50
          a12=a10;    
      elseif a11<=75
          a12=a10+(a10*0.03);
      elseif a11<=100
          a12=a10+(a10*0.06); 
      elseif a11<=125
          a12=a10+(a10*0.09);  
      else
          a12=NaN;
      end
      app.AdjustedWaterContentbasedonWorkabilityEditField.Value=a12;
      a13=app.SHAPEOFAGGREGATEDropDown.Value;
      switch a13
          case'Crushed Angular Aggregate'
             a14=a12;
          case'Sub-Angular Aggregate'
              a14=(a12-10);
          case'Gravel with some Crushed Particles'
              a14=(a12-15);
          case'Rounded Gravel Aggregate'
              a14=(a12-20);
          otherwise
              a14=NaN;
      end
      app.AdjustedWaterContentbasedonShapeofAggregateEditField.Value=a14;
      a15=app.SUPERPLASTICIZERDropDown.Value;
      switch a15
          case'Super Plasticizer-Normal'
              a16=(a14*0.77);
          case'Super Plasticizer-(Polycarboxylate ether based)'
              a16=(a14*0.7);
          case'Accelerating Admixtures'
              a16=(a14*0.7);
          case'Retarding Admixtures'
              a16=(a14*0.9);
           case'Water-Reducing Admixtures'
              a16=(a14*0.7); 
            case'Air-entraining Admixtures'
              a16=(a14*0.7);
            case'NIL'
              a16=(a14);
          otherwise
              a16=NaN;
      end
      app.AdjustedWaterContentbasedontheSuperplasticizerEditField.Value=a16;
      app.FinalCementContentEditField.Value=(a16/app.AdjustedWCratiobasedonExposureConditionsEditField.Value);
      a17=app.FinalCementContentEditField.Value;
      a18=str2double(app.BasedonExperienceDropDown.Value);
       if a18<=0
          a20=a17;
       elseif a18<=10
           a20=(a17*1.1);
       elseif a18<=15
           a20=(a17*1.5);
       elseif a18<=20
           a20=(a17*2);
       else
           a20=NaN;
       end
       app.CementitiousMaterialContentEditField.Value=a20;
     a19=app.TYPESOFMINERALADMIXTURESDropDown.Value;
     switch a19
         case'Ground Granulated Blast-furnace Slag'
             app.GGBSContentEditField.Value=(a20*app.MAEditField.Value/100);
             app.SilicaFumeContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.FlyashContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.MetakaolinContentEditField.Value=(0*a20*app.MAEditField.Value/100); 
         case'Silica Fume'
             app.SilicaFumeContentEditField.Value=(a20*app.MAEditField.Value/100);
             app.GGBSContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.FlyashContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.MetakaolinContentEditField.Value=(0*a20*app.MAEditField.Value/100); 
         case'Flyash'
             app.FlyashContentEditField.Value=(a20*app.MAEditField.Value/100);
             app.GGBSContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.SilicaFumeContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.MetakaolinContentEditField.Value=(0*a20*app.MAEditField.Value/100); 
         case'Metakaolin'
             app.MetakaolinContentEditField.Value=(a20*app.MAEditField.Value/100); 
             app.GGBSContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.SilicaFumeContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.FlyashContentEditField.Value=(0*a20*app.MAEditField.Value/100); 
         case'NIL'
             app.GGBSContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.SilicaFumeContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.FlyashContentEditField.Value=(0*a20*app.MAEditField.Value/100);
             app.MetakaolinContentEditField.Value=(0*a20*app.MAEditField.Value/100); 
         otherwise
             app.GGBSContentEditField.Value=NaN;
             app.SilicaFumeContentEditField.Value=NaN;
             app.FlyashContentEditField.Value=NaN;
             app.MetakaolinContentEditField.Value=NaN; 
     end
     app.FinalRevisedCementContentEditField.Value=(app.CementitiousMaterialContentEditField.Value-app.GGBSContentEditField.Value-app.SilicaFumeContentEditField.Value-app.MetakaolinContentEditField.Value-app.FlyashContentEditField.Value);
     b1=app.MAXSIZECOARSEOFAGGREGATEDropDown.Value;
     b2=app.ZONEOFFINEAGGREGATEDropDown.Value;
     combinedValue=strcat(b1,'-',b2);
     switch combinedValue
         case'8-ZONE-I'
            b3=0.46;
         case'10-ZONE-I'
             b3=0.48;
         case'12.5-ZONE-I'
             b3=0.50;
         case'20-ZONE-I'
             b3=0.60;
         case'40-ZONE-I'
             b3=0.69;   
         case'80-ZONE-I'
             b3=0.72;
         case'150-ZONE-I'
             b3=0.77;
         case'8-ZONE-II'
            b3=0.48;
         case'10-ZONE-II'
             b3=0.50;
         case'12.5-ZONE-II'
             b3=0.52;
         case'20-ZONE-II'
             b3=0.62;
         case'40-ZONE-II'
             b3=0.71;   
         case'80-ZONE-II'
             b3=0.73;
         case'150-ZONE-II'
             b3=0.78;   
         case'8-ZONE-III'
            b3=0.50;
         case'10-ZONE-III'
             b3=0.52;
         case'12.5-ZONE-III'
             b3=0.54;
         case'20-ZONE-III'
             b3=0.64;
         case'40-ZONE-III'
             b3=0.72;   
         case'80-ZONE-III'
             b3=0.74;
         case'150-ZONE-III'
             b3=0.79;
         case'8-ZONE-IV'
            b3=0.52;
         case'10-ZONE-IV'
             b3=0.54;
         case'12.5-ZONE-IV'
             b3=0.56;
         case'20-ZONE-IV'
             b3=0.66;
         case'40-ZONE-IV'
             b3=0.73;   
         case'80-ZONE-IV'
             b3=0.75;
         case'150-ZONE-IV'
             b3=0.80;
         otherwise
             b3=NaN;
     end
     app.VolumeofCoarseAggregateforCategory1EditField.Value=((b3)-(((app.AdjustedWCratiobasedonExposureConditionsEditField.Value-0.5)/0.05)*0.01));
     b4=app.MAXSIZECOARSEOFAGGREGATEDropDown.Value;
     b5=app.ZONEOFFINEAGGREGATEDropDown.Value;
     combinedValue=strcat(b4,'-',b5);
     switch combinedValue
         case'8-ZONE-I'
            b5=0.416;
         case'10-ZONE-I'
             b5=0.52;
         case'12.5-ZONE-I'
             b5=0.54;
         case'20-ZONE-I'
             b5=0.64;
         case'8-ZONE-II'
            b5=0.432;
         case'10-ZONE-II'
             b5=0.54;
         case'12.5-ZONE-II'
             b5=0.56;
         case'20-ZONE-II'
             b5=0.66;  
         case'8-ZONE-III'
            b5=0.448;
         case'10-ZONE-III'
             b5=0.56;
         case'12.5-ZONE-III'
             b5=0.58;
         case'20-ZONE-III'
             b5=0.68;
         otherwise
             b5=NaN;
     end
     app.VolumeofCoarseAggregateforCategory2EditField.Value=((b5)-(((app.AdjustedWCratiobasedonExposureConditionsEditField.Value-0.3)/0.05)*0.01));
     b6=app.VolumeofCoarseAggregateforCategory1EditField.Value;
     b7=app.VolumeofCoarseAggregateforCategory2EditField.Value;
     b8=app.AdjustedWCratiobasedonExposureConditionsEditField.Value;
     if b8<=0.3
        b9=b7;
     elseif b8>0.3
         b9=b6;
     else
         b9=NaN;
     end
     app.AdjustedVolumneofCoarseAggregatebasedonWCEditField.Value=b9;
     app.VolumeoffineAggregateperunitvolumeofAggregateEditField.Value=(1-b9);
     app.VolumeofEntrappedAirEditField.Value=(app.ApproxFinalAirContentEditField.Value/100);
     app.VolumeofCementEditField.Value=((app.FinalRevisedCementContentEditField.Value/app.SpecificGravityofCementEditField.Value)*(1/1000));
     app.VolumeofWaterEditField.Value=((app.AdjustedWaterContentbasedontheSuperplasticizerEditField.Value/app.SpecificGravityofWaterEditField.Value)*(1/1000));
     c1=app.TYPESOFMINERALADMIXTURESDropDown.Value;
     switch c1
         case'Ground Granulated Blast-furnace Slag'
             app.VolumeofGGBSEditField.Value=((app.GGBSContentEditField.Value/app.SpecificGravityofGGBSEditField.Value)*(1/1000));
             app.VolumeofSilicaFumeEditField.Value=0;
             app.VolumeofFlyAshEditField.Value=0;
             app.VolumeofMetakaolinEditField.Value=0; 
         case'Silica Fume'
             app.VolumeofSilicaFumeEditField.Value=((app.SilicaFumeContentEditField.Value/app.SpecificGravityofSilicaFumeEditField.Value)*(1/1000));
             app.VolumeofGGBSEditField.Value=0;
             app.VolumeofFlyAshEditField.Value=0;
             app.VolumeofMetakaolinEditField.Value=0; 
         case'Flyash'
             app.VolumeofFlyAshEditField.Value=((app.FlyashContentEditField.Value/app.SpecificGravityofFlyashEditField.Value)*(1/1000));
             app.VolumeofGGBSEditField.Value=0;
             app.VolumeofSilicaFumeEditField.Value=0;
             app.VolumeofMetakaolinEditField.Value=0; 
         case'Metakaolin'
             app.VolumeofMetakaolinEditField.Value=((app.MetakaolinContentEditField.Value/app.SpecificGravityofMetakaolinEditField.Value)*(1/1000));
             app.VolumeofGGBSEditField.Value=0;
             app.VolumeofSilicaFumeEditField.Value=0;
             app.VolumeofFlyAshEditField.Value=0;
         case'NIL'
             app.VolumeofGGBSEditField.Value=0;
             app.VolumeofSilicaFumeEditField.Value=0;
             app.VolumeofFlyAshEditField.Value=0;
             app.VolumeofMetakaolinEditField.Value=0; 
         otherwise
             app.VolumeofGGBSEditField.Value=NaN;
             app.VolumeofSilicaFumeEditField.Value=NaN;
             app.VolumeofFlyAshEditField.Value=NaN;
             app.VolumeofMetakaolinEditField.Value=NaN; 
     end
     b10=app.SUPERPLASTICIZERDropDown.Value;
      switch b10
          case'Super Plasticizer-Normal'
              b11=1/100;
          case'Super Plasticizer-(Polycarboxylate ether based)'
              b11=0.5/100;
          case'Accelerating Admixtures'
              b11=0.5/100;
          case'Retarding Admixtures'
              b11=0.5/100;
           case'Water-Reducing Admixtures'
              b11=0.5/100; 
            case'Air-entraining Admixtures'
              b11=0.5/100;
            case'NIL'
              b11=0/100;
          otherwise
              b11=NaN;
      end
      app.MassofCementitiousMaterialEditField.Value=b11;
      c2=app.SUPERPLASTICIZERDropDown.Value;
      switch c2
         case'Super Plasticizer-Normal'
             app.VolumeofChemicalAdmixtureEditField.Value=(app.CementitiousMaterialContentEditField.Value*b11)/(app.SpecificGravityofChemicalAdmixtureEditField.Value)*(1/1000);
         case'Super Plasticizer-(Polycarboxylate ether based)'
             app.VolumeofChemicalAdmixtureEditField.Value=(app.CementitiousMaterialContentEditField.Value*b11)/(app.SpecificGravityofChemicalAdmixtureEditField.Value)*(1/1000);
         case'Accelerating Admixtures'
             app.VolumeofChemicalAdmixtureEditField.Value=(app.CementitiousMaterialContentEditField.Value*b11)/(app.SpecificGravityofChemicalAdmixtureEditField.Value)*(1/1000);
         case'Water-Reducing Admixtures'
             app.VolumeofChemicalAdmixtureEditField.Value=(app.CementitiousMaterialContentEditField.Value*b11)/(app.SpecificGravityofChemicalAdmixtureEditField.Value)*(1/1000);
          case'Air-entraining Admixtures'
              app.VolumeofChemicalAdmixtureEditField.Value=(app.CementitiousMaterialContentEditField.Value*b11)/(app.SpecificGravityofChemicalAdmixtureEditField.Value)*(1/1000);
         case'NIL'
             app.VolumeofChemicalAdmixtureEditField.Value=0; 
         otherwise
             app.VolumeofChemicalAdmixtureEditField.Value=0;       
      end
      app.VolumeofallinAggregateEditField.Value=(app.TotalVolumeEditField.Value-app.VolumeofEntrappedAirEditField.Value)-(app.VolumeofCementEditField.Value+app.VolumeofWaterEditField.Value+app.VolumeofFlyAshEditField.Value+app.VolumeofSilicaFumeEditField.Value+app.VolumeofGGBSEditField.Value+app.VolumeofMetakaolinEditField.Value+app.VolumeofChemicalAdmixtureEditField.Value);
      app.MassofCoarseAggregateEditField.Value=(app.VolumeofallinAggregateEditField.Value*app.AdjustedVolumneofCoarseAggregatebasedonWCEditField.Value*app.SpecificGravityofCoarseAggregateEditField.Value*1000);
      app.MassofFineAggregateEditField.Value=(app.VolumeoffineAggregateperunitvolumeofAggregateEditField.Value*app.VolumeofallinAggregateEditField.Value*app.SpecificGravityofFineAggregateEditField.Value*1000);
      app.MassofChemicalAdmixtureEditField.Value=(app.MassofCementitiousMaterialEditField.Value*app.CementitiousMaterialContentEditField.Value);
      app.CementContentEditField.Value=app.FinalRevisedCementContentEditField.Value;
      app.FlyAshEditField.Value=app.FlyashContentEditField.Value;
      app.SilicaFumeEditField.Value=app.SilicaFumeContentEditField.Value;
      app.GGBSEditField.Value=app.GGBSContentEditField.Value;
      app.MetakaolinEditField.Value=app.MetakaolinContentEditField.Value;
      app.WaterEditField.Value=app.AdjustedWaterContentbasedontheSuperplasticizerEditField.Value;
      app.FineAggregateEditField.Value=app.MassofFineAggregateEditField.Value;
      app.CoarseAggregateEditField.Value=app.MassofCoarseAggregateEditField.Value;
      app.WCmEditField.Value=app.AdjustedWCratiobasedonExposureConditionsEditField.Value;
      app.ChemicalAdmixtureEditField.Value=app.MassofChemicalAdmixtureEditField.Value;
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RESULTSButton = uibutton(app.UIFigure, 'push');
            app.RESULTSButton.ButtonPushedFcn = createCallbackFcn(app, @RESULTSButtonPushed, true);
            app.RESULTSButton.Text = 'RESULTS';
            app.BasedonStandardDeviationLabel = uilabel(app.UIFigure);
            app.BasedonStandardDeviationLabel.Text = 'Based on Standard Deviation';
            app.BasedonFactorforGradeofconcreteLabel = uilabel(app.UIFigure);
            app.BasedonFactorforGradeofconcreteLabel.Text = 'Based on Factor for Grade of concrete';
            app.MPaLabel = uilabel(app.UIFigure);
            app.MPaLabel.Text = 'MPa';
            app.PLAINCONCRETELabel = uilabel(app.UIFigure);
            app.PLAINCONCRETELabel.Text = 'PLAIN CONCRETE';
            app.REINFORCEDCONCRETELabel = uilabel(app.UIFigure);
            app.REINFORCEDCONCRETELabel.Text = 'REINFORCED CONCRETE';
            app.SelectionofWaterCementRatioLabel = uilabel(app.UIFigure);
            app.SelectionofWaterCementRatioLabel.Text = 'Selection of Water-Cement Ratio';
            app.ForTargetStrengthUpto70MPaFromGraphLabel = uilabel(app.UIFigure);
            app.ForTargetStrengthUpto70MPaFromGraphLabel.Text = 'For Target Strength Upto 70 MPa(From Graph)';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.GRADEOFCONCRETEDropDownLabel = uilabel(app.UIFigure);
            app.GRADEOFCONCRETEDropDownLabel.Text = 'GRADE OF CONCRETE';
            app.GRADEOFCONCRETEDropDown = uidropdown(app.UIFigure);
            app.GRADEOFCONCRETEDropDown.Items = {'10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60'};
            app.GRADEOFCONCRETEDropDown.Value = '20';
            app.TYPEOFCEMENTDropDownLabel = uilabel(app.UIFigure);
            app.TYPEOFCEMENTDropDownLabel.Text = 'TYPE OF CEMENT';
            app.TYPEOFCEMENTDropDown = uidropdown(app.UIFigure);
            app.TYPEOFCEMENTDropDown.Items = {'OPC 33 grade conforming to IS 269-2015', 'OPC 43 grade conforming to IS 269-2015', 'OPC 53 grade conforming to IS 269-2015', 'OPC 43S grade conforming to IS 269-2015', 'OPC 53S grade conforming to IS 269-2015', 'PPC-(Flyash Based) grade conforming to IS 1489-Part-I', 'PPC(Calcined Clay Based) grade conforming to to IS 1489-Part-II'};
            app.TYPEOFCEMENTDropDown.Value = 'OPC 33 grade conforming to IS 269-2015';
            app.TYPESOFMINERALADMIXTURESLabel = uilabel(app.UIFigure);
            app.TYPESOFMINERALADMIXTURESLabel.Text = {'TYPES OF MINERAL     '; '           ADMIXTURES'};
            app.TYPESOFMINERALADMIXTURESDropDown = uidropdown(app.UIFigure);
            app.TYPESOFMINERALADMIXTURESDropDown.Items = {'Ground Granulated Blast-furnace Slag', 'Silica Fume', 'Flyash', 'Metakaolin', 'NIL'};
            app.TYPESOFMINERALADMIXTURESDropDown.Value = 'Ground Granulated Blast-furnace Slag';
            app.MAXSIZEOFAGGREGATELabel = uilabel(app.UIFigure);
            app.MAXSIZEOFAGGREGATELabel.Text = {'MAX SIZE COARSE OF     '; '          AGGREGATE'};
            app.MAXSIZECOARSEOFAGGREGATEDropDown = uidropdown(app.UIFigure);
            app.MAXSIZECOARSEOFAGGREGATEDropDown.Items = {'8', '10', '12.5', '20', '40', '80', '150'};
            app.MAXSIZECOARSEOFAGGREGATEDropDown.Value = '8';
            app.EXPOSURECONDITIONDropDownLabel = uilabel(app.UIFigure);
            app.EXPOSURECONDITIONDropDownLabel.Text = 'EXPOSURE CONDITION';
            app.EXPOSURECONDITIONDropDown = uidropdown(app.UIFigure);
            app.EXPOSURECONDITIONDropDown.Items = {'Mild', 'Moderate', 'Severe', 'Very Severe', 'Extreme'};
            app.EXPOSURECONDITIONDropDown.Value = 'Mild';
            app.WORKABILITYDropDownLabel = uilabel(app.UIFigure);
            app.WORKABILITYDropDownLabel.Text = 'WORKABILITY';
            app.WORKABILITYDropDown = uidropdown(app.UIFigure);
            app.WORKABILITYDropDown.Items = {'25', '50', '75', '100', '125'};
            app.WORKABILITYDropDown.Value = '25';
            app.METHODOFCONCRETEPLACINGLabel = uilabel(app.UIFigure);
            app.METHODOFCONCRETEPLACINGLabel.Text = {'METHOD OF CONCRETE     '; '      PLACING'};
            app.METHODOFCONCRETEPLACINGDropDown = uidropdown(app.UIFigure);
            app.METHODOFCONCRETEPLACINGDropDown.Items = {'Pumpable', 'Chute(Non-Pumpable)'};
            app.METHODOFCONCRETEPLACINGDropDown.Value = 'Pumpable';
            app.DEGREEOFSUPERVISIONDropDownLabel = uilabel(app.UIFigure);
            app.DEGREEOFSUPERVISIONDropDownLabel.Text = 'DEGREE OF SUPERVISION';
            app.DEGREEOFSUPERVISIONDropDown = uidropdown(app.UIFigure);
            app.DEGREEOFSUPERVISIONDropDown.Items = {'GOOD'};
            app.DEGREEOFSUPERVISIONDropDown.Value = 'GOOD';
            app.SHAPEOFAGGREGATEDropDownLabel = uilabel(app.UIFigure);
            app.SHAPEOFAGGREGATEDropDownLabel.Text = ' SHAPE OF AGGREGATE';
            app.SHAPEOFAGGREGATEDropDown = uidropdown(app.UIFigure);
            app.SHAPEOFAGGREGATEDropDown.Items = {'Crushed Angular Aggregate', 'Sub-Angular Aggregate', 'Gravel with some Crushed Particles', 'Rounded Gravel Aggregate'};
            app.SHAPEOFAGGREGATEDropDown.Value = 'Crushed Angular Aggregate';
            app.MAXCEMENTCONTENTDropDownLabel = uilabel(app.UIFigure);
            app.MAXCEMENTCONTENTDropDownLabel.Text = 'MAX CEMENT CONTENT ';
            app.MAXCEMENTCONTENTDropDown = uidropdown(app.UIFigure);
            app.MAXCEMENTCONTENTDropDown.Items = {'450 kg/m3'};
            app.MAXCEMENTCONTENTDropDown.Value = '450 kg/m3';
            app.SUPERPLASTICIZERDropDownLabel = uilabel(app.UIFigure);
            app.SUPERPLASTICIZERDropDownLabel.Text = 'SUPER PLASTICIZER';
            app.SUPERPLASTICIZERDropDown = uidropdown(app.UIFigure);
            app.SUPERPLASTICIZERDropDown.Items = {'Super Plasticizer-Normal', 'Super Plasticizer-(Polycarboxylate ether based)', 'Accelerating Admixtures', 'Retarding Admixtures', 'Water-Reducing Admixtures', 'Air-entraining Admixtures', 'NIL'};
            app.SUPERPLASTICIZERDropDown.Value = 'Super Plasticizer-Normal';
            app.VISCOSITYMODIFYINGAGENTDropDownLabel = uilabel(app.UIFigure);
            app.VISCOSITYMODIFYINGAGENTDropDownLabel.Text = {'VISCOSITY MODIFYING     '; '          AGENT'};
            app.VISCOSITYMODIFYINGAGENTDropDown = uidropdown(app.UIFigure);
            app.VISCOSITYMODIFYINGAGENTDropDown.Items = {'NIL'};
            app.VISCOSITYMODIFYINGAGENTDropDown.Value = 'NIL';
            app.ZONEOFFINEAGGREGATEDropDownLabel = uilabel(app.UIFigure);
            app.ZONEOFFINEAGGREGATEDropDownLabel.Text = 'ZONE OF FINE AGGREGATE';
            app.ZONEOFFINEAGGREGATEDropDown = uidropdown(app.UIFigure);
            app.ZONEOFFINEAGGREGATEDropDown.Items = {'ZONE-I', 'ZONE-II', 'ZONE-III', 'ZONE-IV'};
            app.ZONEOFFINEAGGREGATEDropDown.Value = 'ZONE-I';
            app.TARGETSTRENGTHBASEDONSDEditFieldLabel = uilabel(app.UIFigure);
            app.TARGETSTRENGTHBASEDONSDEditFieldLabel.Text = 'TARGET STRENGTH BASED ON S.D';
            app.TARGETSTRENGTHBASEDONSDEditField = uieditfield(app.UIFigure, 'numeric');
            app.TARGETSTRENGTHBASEDONSDEditField.AllowEmpty = 'on';
            app.TARGETSTRENGTHBASEDONSDEditField.Editable = 'off';
            app.TARGETSTRENGTHBASEDONSDEditField.Value = [];
            app.StandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.UIFigure, 'numeric');
            app.StandardDeviationEditField.AllowEmpty = 'on';
            app.StandardDeviationEditField.Editable = 'off';
            app.StandardDeviationEditField.Value = [];
            app.TARGETSTRENGTHBASEDONFACTORLabel = uilabel(app.UIFigure);
            app.TARGETSTRENGTHBASEDONFACTORLabel.Text = {'TARGET STRENGTH BASED ON     '; '   FACTOR'};
            app.TARGETSTRENGTHBASEDONFACTOREditField = uieditfield(app.UIFigure, 'numeric');
            app.TARGETSTRENGTHBASEDONFACTOREditField.AllowEmpty = 'on';
            app.TARGETSTRENGTHBASEDONFACTOREditField.Editable = 'off';
            app.TARGETSTRENGTHBASEDONFACTOREditField.Value = [];
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.AllowEmpty = 'on';
            app.XEditField.Editable = 'off';
            app.XEditField.Value = [];
            app.FINALTARGETSTRENGTHEditFieldLabel = uilabel(app.UIFigure);
            app.FINALTARGETSTRENGTHEditFieldLabel.Text = 'FINAL TARGET STRENGTH';
            app.FINALTARGETSTRENGTHEditField = uieditfield(app.UIFigure, 'numeric');
            app.FINALTARGETSTRENGTHEditField.AllowEmpty = 'on';
            app.FINALTARGETSTRENGTHEditField.Editable = 'off';
            app.FINALTARGETSTRENGTHEditField.Value = [];
            app.ApproxAirContentGradeuptoM60EditFieldLabel = uilabel(app.UIFigure);
            app.ApproxAirContentGradeuptoM60EditFieldLabel.Text = 'Approx Air Content(Grade upto M60)';
            app.ApproxAirContentGradeuptoM60EditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproxAirContentGradeuptoM60EditField.AllowEmpty = 'on';
            app.ApproxAirContentGradeuptoM60EditField.Editable = 'off';
            app.ApproxAirContentGradeuptoM60EditField.Value = [];
            app.ApproxAircontentABOVEGRADEM65EditFieldLabel = uilabel(app.UIFigure);
            app.ApproxAircontentABOVEGRADEM65EditFieldLabel.Text = 'Approx Air content(ABOVE GRADE-M65) ';
            app.ApproxAircontentABOVEGRADEM65EditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproxAircontentABOVEGRADEM65EditField.AllowEmpty = 'on';
            app.ApproxAircontentABOVEGRADEM65EditField.Editable = 'off';
            app.ApproxAircontentABOVEGRADEM65EditField.Value = [];
            app.ApproxFinalAirContentEditFieldLabel = uilabel(app.UIFigure);
            app.ApproxFinalAirContentEditFieldLabel.Text = 'Approx Final Air Content';
            app.ApproxFinalAirContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.ApproxFinalAirContentEditField.AllowEmpty = 'on';
            app.ApproxFinalAirContentEditField.Editable = 'off';
            app.ApproxFinalAirContentEditField.Value = [];
            app.MinCementContent_PlainEditFieldLabel = uilabel(app.UIFigure);
            app.MinCementContent_PlainEditFieldLabel.Text = 'Min Cement Content_Plain';
            app.MinCementContent_PlainEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCementContent_PlainEditField.AllowEmpty = 'on';
            app.MinCementContent_PlainEditField.Editable = 'off';
            app.MinCementContent_PlainEditField.Value = [];
            app.MaxWCRatio_PlainEditFieldLabel = uilabel(app.UIFigure);
            app.MaxWCRatio_PlainEditFieldLabel.Text = 'Max W/C Ratio_Plain';
            app.MaxWCRatio_PlainEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxWCRatio_PlainEditField.AllowEmpty = 'on';
            app.MaxWCRatio_PlainEditField.Editable = 'off';
            app.MaxWCRatio_PlainEditField.Value = [];
            app.MinimumGrade_PlainEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumGrade_PlainEditFieldLabel.Text = 'Minimum Grade_Plain';
            app.MinimumGrade_PlainEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumGrade_PlainEditField.AllowEmpty = 'on';
            app.MinimumGrade_PlainEditField.Editable = 'off';
            app.MinimumGrade_PlainEditField.Value = [];
            app.MinCementContent_RCEditFieldLabel = uilabel(app.UIFigure);
            app.MinCementContent_RCEditFieldLabel.Text = 'Min Cement Content_RC';
            app.MinCementContent_RCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCementContent_RCEditField.AllowEmpty = 'on';
            app.MinCementContent_RCEditField.Editable = 'off';
            app.MinCementContent_RCEditField.Value = [];
            app.MaxWCRatio_RCEditFieldLabel = uilabel(app.UIFigure);
            app.MaxWCRatio_RCEditFieldLabel.Text = 'Max W/C Ratio_RC';
            app.MaxWCRatio_RCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxWCRatio_RCEditField.AllowEmpty = 'on';
            app.MaxWCRatio_RCEditField.Editable = 'off';
            app.MaxWCRatio_RCEditField.Value = [];
            app.MinimumGrade_RCEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumGrade_RCEditFieldLabel.Text = 'Minimum Grade_RC';
            app.MinimumGrade_RCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumGrade_RCEditField.AllowEmpty = 'on';
            app.MinimumGrade_RCEditField.Editable = 'off';
            app.MinimumGrade_RCEditField.Value = [];
            app.FOROPC53EditFieldLabel = uilabel(app.UIFigure);
            app.FOROPC53EditFieldLabel.Text = 'FOR OPC-53';
            app.FOROPC53EditField = uieditfield(app.UIFigure, 'numeric');
            app.FOROPC53EditField.AllowEmpty = 'on';
            app.FOROPC53EditField.Editable = 'off';
            app.FOROPC53EditField.Value = [];
            app.FOROPC43EditFieldLabel = uilabel(app.UIFigure);
            app.FOROPC43EditFieldLabel.Text = 'FOR OPC-43';
            app.FOROPC43EditField = uieditfield(app.UIFigure, 'numeric');
            app.FOROPC43EditField.AllowEmpty = 'on';
            app.FOROPC43EditField.Editable = 'off';
            app.FOROPC43EditField.Value = [];
            app.FOROPC33EditFieldLabel = uilabel(app.UIFigure);
            app.FOROPC33EditFieldLabel.Text = 'FOR OPC-33';
            app.FOROPC33EditField = uieditfield(app.UIFigure, 'numeric');
            app.FOROPC33EditField.AllowEmpty = 'on';
            app.FOROPC33EditField.Editable = 'off';
            app.FOROPC33EditField.Value = [];
            app.FreeWaterCementRatioEditFieldLabel = uilabel(app.UIFigure);
            app.FreeWaterCementRatioEditFieldLabel.Text = 'Free Water Cement Ratio ';
            app.FreeWaterCementRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.FreeWaterCementRatioEditField.AllowEmpty = 'on';
            app.FreeWaterCementRatioEditField.Editable = 'off';
            app.AdjustedWCratiobasedonExposureConditionsEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustedWCratiobasedonExposureConditionsEditFieldLabel.Text = 'Adjusted W/C ratio based on Exposure Conditions';
            app.AdjustedWCratiobasedonExposureConditionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustedWCratiobasedonExposureConditionsEditField.AllowEmpty = 'on';
            app.AdjustedWCratiobasedonExposureConditionsEditField.Editable = 'off';
            app.MAXWCEditFieldLabel = uilabel(app.UIFigure);
            app.MAXWCEditFieldLabel.Text = 'MAX W/C';
            app.MAXWCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MAXWCEditField.AllowEmpty = 'on';
            app.MAXWCEditField.Editable = 'off';
            app.MAXWCEditField.Value = [];
            app.TYPEOFCONCRETEDropDownLabel = uilabel(app.UIFigure);
            app.TYPEOFCONCRETEDropDownLabel.Text = 'TYPE OF CONCRETE';
            app.TYPEOFCONCRETEDropDown = uidropdown(app.UIFigure);
            app.TYPEOFCONCRETEDropDown.Items = {'PLAIN CONCRETE', 'REINFORCED CONCRETE'};
            app.TYPEOFCONCRETEDropDown.Value = 'PLAIN CONCRETE';
            app.MinCementContentEditFieldLabel = uilabel(app.UIFigure);
            app.MinCementContentEditFieldLabel.Text = 'Min Cement Content';
            app.MinCementContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinCementContentEditField.AllowEmpty = 'on';
            app.MinCementContentEditField.Editable = 'off';
            app.MinCementContentEditField.Value = [];
            app.MinimumGradeEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumGradeEditFieldLabel.Text = 'Minimum Grade';
            app.MinimumGradeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumGradeEditField.AllowEmpty = 'on';
            app.MinimumGradeEditField.Editable = 'off';
            app.MinimumGradeEditField.Value = [];
            app.AdjustedWaterContentbasedonWorkabilityEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustedWaterContentbasedonWorkabilityEditFieldLabel.Text = ' Adjusted Water Content based on Workability';
            app.AdjustedWaterContentbasedonWorkabilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustedWaterContentbasedonWorkabilityEditField.AllowEmpty = 'on';
            app.AdjustedWaterContentbasedonWorkabilityEditField.Editable = 'off';
            app.AdjustedWaterContentbasedonWorkabilityEditField.Value = [];
            app.InitialEstimatedWatercontentEditFieldLabel = uilabel(app.UIFigure);
            app.InitialEstimatedWatercontentEditFieldLabel.Text = 'Initial Estimated Water content';
            app.InitialEstimatedWatercontentEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialEstimatedWatercontentEditField.AllowEmpty = 'on';
            app.InitialEstimatedWatercontentEditField.Editable = 'off';
            app.InitialEstimatedWatercontentEditField.Value = [];
            app.AdjustedWaterContentbasedonShapeofAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustedWaterContentbasedonShapeofAggregateEditFieldLabel.Text = 'Adjusted Water Content based on Shape of Aggregate';
            app.AdjustedWaterContentbasedonShapeofAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustedWaterContentbasedonShapeofAggregateEditField.AllowEmpty = 'on';
            app.AdjustedWaterContentbasedonShapeofAggregateEditField.Editable = 'off';
            app.AdjustedWaterContentbasedonShapeofAggregateEditField.Value = [];
            app.AdjustedWaterContentbasedontheSuperplasticizerEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustedWaterContentbasedontheSuperplasticizerEditFieldLabel.Text = 'Adjusted Water Content based on the Superplasticizer ';
            app.AdjustedWaterContentbasedontheSuperplasticizerEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustedWaterContentbasedontheSuperplasticizerEditField.AllowEmpty = 'on';
            app.AdjustedWaterContentbasedontheSuperplasticizerEditField.Editable = 'off';
            app.AdjustedWaterContentbasedontheSuperplasticizerEditField.Value = [];
            app.FinalCementContentEditFieldLabel = uilabel(app.UIFigure);
            app.FinalCementContentEditFieldLabel.Text = 'Final Cement Content';
            app.FinalCementContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalCementContentEditField.AllowEmpty = 'on';
            app.FinalCementContentEditField.Editable = 'off';
            app.FinalCementContentEditField.Value = [];
            app.CementitiousMaterialContentEditFieldLabel = uilabel(app.UIFigure);
            app.CementitiousMaterialContentEditFieldLabel.Text = ' Cementitious Material Content';
            app.CementitiousMaterialContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.CementitiousMaterialContentEditField.AllowEmpty = 'on';
            app.CementitiousMaterialContentEditField.Editable = 'off';
            app.CementitiousMaterialContentEditField.Value = [];
            app.SilicaFumeContentEditFieldLabel = uilabel(app.UIFigure);
            app.SilicaFumeContentEditFieldLabel.Text = 'Silica Fume Content';
            app.SilicaFumeContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.SilicaFumeContentEditField.AllowEmpty = 'on';
            app.SilicaFumeContentEditField.Editable = 'off';
            app.SilicaFumeContentEditField.Value = [];
            app.FlyashContentEditFieldLabel = uilabel(app.UIFigure);
            app.FlyashContentEditFieldLabel.Text = 'Flyash Content';
            app.FlyashContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlyashContentEditField.AllowEmpty = 'on';
            app.FlyashContentEditField.Editable = 'off';
            app.FlyashContentEditField.Value = [];
            app.GGBSContentEditFieldLabel = uilabel(app.UIFigure);
            app.GGBSContentEditFieldLabel.Text = 'GGBS Content';
            app.GGBSContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.GGBSContentEditField.AllowEmpty = 'on';
            app.GGBSContentEditField.Editable = 'off';
            app.GGBSContentEditField.Value = [];
            app.MetakaolinContentEditFieldLabel = uilabel(app.UIFigure);
            app.MetakaolinContentEditFieldLabel.Text = 'Metakaolin Content';
            app.MetakaolinContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetakaolinContentEditField.AllowEmpty = 'on';
            app.MetakaolinContentEditField.Editable = 'off';
            app.MetakaolinContentEditField.Value = [];
            app.FinalRevisedCementContentEditFieldLabel = uilabel(app.UIFigure);
            app.FinalRevisedCementContentEditFieldLabel.Text = 'Final Revised Cement Content';
            app.FinalRevisedCementContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinalRevisedCementContentEditField.AllowEmpty = 'on';
            app.FinalRevisedCementContentEditField.Editable = 'off';
            app.FinalRevisedCementContentEditField.Value = [];
            app.BasedonExperienceLabel = uilabel(app.UIFigure);
            app.BasedonExperienceLabel.Text = '(Based on Experience)';
            app.BasedonExperienceDropDown = uidropdown(app.UIFigure);
            app.BasedonExperienceDropDown.Items = {'0', '10', '15', '20'};
            app.BasedonExperienceDropDown.Value = '0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'M.A %';
            app.MAEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofCoarseAggregateforCategory1EditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofCoarseAggregateforCategory1EditFieldLabel.Text = 'Volume of Coarse Aggregate for Category1';
            app.VolumeofCoarseAggregateforCategory1EditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofCoarseAggregateforCategory1EditField.AllowEmpty = 'on';
            app.VolumeofCoarseAggregateforCategory1EditField.Editable = 'off';
            app.VolumeofCoarseAggregateforCategory1EditField.Value = [];
            app.VolumeofCoarseAggregateforCategory2EditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofCoarseAggregateforCategory2EditFieldLabel.Text = 'Volume of Coarse Aggregate for Category2';
            app.VolumeofCoarseAggregateforCategory2EditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofCoarseAggregateforCategory2EditField.AllowEmpty = 'on';
            app.VolumeofCoarseAggregateforCategory2EditField.Editable = 'off';
            app.VolumeofCoarseAggregateforCategory2EditField.Value = [];
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditFieldLabel = uilabel(app.UIFigure);
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditFieldLabel.Text = 'Adjusted Volumne of Coarse Aggregate based on W/C';
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditField = uieditfield(app.UIFigure, 'numeric');
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditField.AllowEmpty = 'on';
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditField.Editable = 'off';
            app.AdjustedVolumneofCoarseAggregatebasedonWCEditField.Value = [];
            app.VolumeoffineAggregateperunitvolumeofAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeoffineAggregateperunitvolumeofAggregateEditFieldLabel.Text = 'Volume of fine Aggregate per unit volume of Aggregate';
            app.VolumeoffineAggregateperunitvolumeofAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeoffineAggregateperunitvolumeofAggregateEditField.AllowEmpty = 'on';
            app.VolumeoffineAggregateperunitvolumeofAggregateEditField.Editable = 'off';
            app.VolumeoffineAggregateperunitvolumeofAggregateEditField.Value = [];
            app.TotalVolumeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalVolumeEditFieldLabel.Text = 'Total Volume';
            app.TotalVolumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalVolumeEditField.Editable = 'off';
            app.TotalVolumeEditField.Value = 1;
            app.VolumeofEntrappedAirEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofEntrappedAirEditFieldLabel.Text = 'Volume of Entrapped Air';
            app.VolumeofEntrappedAirEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofEntrappedAirEditField.AllowEmpty = 'on';
            app.VolumeofEntrappedAirEditField.Editable = 'off';
            app.VolumeofEntrappedAirEditField.Value = [];
            app.VolumeofCementEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofCementEditFieldLabel.Text = 'Volume of Cement';
            app.VolumeofCementEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofCementEditField.AllowEmpty = 'on';
            app.VolumeofCementEditField.Editable = 'off';
            app.VolumeofCementEditField.Value = [];
            app.VolumeofWaterEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofWaterEditFieldLabel.Text = 'Volume of Water';
            app.VolumeofWaterEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofWaterEditField.AllowEmpty = 'on';
            app.VolumeofWaterEditField.Editable = 'off';
            app.VolumeofWaterEditField.Value = [];
            app.VolumeofFlyAshEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofFlyAshEditFieldLabel.Text = 'Volume of Fly Ash';
            app.VolumeofFlyAshEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofFlyAshEditField.AllowEmpty = 'on';
            app.VolumeofFlyAshEditField.Editable = 'off';
            app.VolumeofFlyAshEditField.Value = [];
            app.VolumeofSilicaFumeEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofSilicaFumeEditFieldLabel.Text = 'Volume of Silica Fume';
            app.VolumeofSilicaFumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofSilicaFumeEditField.AllowEmpty = 'on';
            app.VolumeofSilicaFumeEditField.Editable = 'off';
            app.VolumeofSilicaFumeEditField.Value = [];
            app.VolumeofGGBSEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofGGBSEditFieldLabel.Text = 'Volume of GGBS';
            app.VolumeofGGBSEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofGGBSEditField.AllowEmpty = 'on';
            app.VolumeofGGBSEditField.Editable = 'off';
            app.VolumeofGGBSEditField.Value = [];
            app.VolumeofMetakaolinEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofMetakaolinEditFieldLabel.Text = 'Volume of Metakaolin';
            app.VolumeofMetakaolinEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofMetakaolinEditField.AllowEmpty = 'on';
            app.VolumeofMetakaolinEditField.Editable = 'off';
            app.VolumeofMetakaolinEditField.Value = [];
            app.VolumeofChemicalAdmixtureEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofChemicalAdmixtureEditFieldLabel.Text = 'Volume of Chemical Admixture';
            app.VolumeofChemicalAdmixtureEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofChemicalAdmixtureEditField.AllowEmpty = 'on';
            app.VolumeofChemicalAdmixtureEditField.Editable = 'off';
            app.VolumeofChemicalAdmixtureEditField.Value = [];
            app.VolumeofallinAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofallinAggregateEditFieldLabel.Text = 'Volume of all in Aggregate';
            app.VolumeofallinAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofallinAggregateEditField.AllowEmpty = 'on';
            app.VolumeofallinAggregateEditField.Editable = 'off';
            app.VolumeofallinAggregateEditField.Value = [];
            app.MassofCoarseAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.MassofCoarseAggregateEditFieldLabel.Text = 'Mass of Coarse Aggregate';
            app.MassofCoarseAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofCoarseAggregateEditField.AllowEmpty = 'on';
            app.MassofCoarseAggregateEditField.Editable = 'off';
            app.MassofCoarseAggregateEditField.Value = [];
            app.MassofFineAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.MassofFineAggregateEditFieldLabel.Text = 'Mass of Fine Aggregate';
            app.MassofFineAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofFineAggregateEditField.AllowEmpty = 'on';
            app.MassofFineAggregateEditField.Editable = 'off';
            app.MassofFineAggregateEditField.Value = [];
            app.MassofChemicalAdmixtureEditFieldLabel = uilabel(app.UIFigure);
            app.MassofChemicalAdmixtureEditFieldLabel.Text = 'Mass of Chemical Admixture';
            app.MassofChemicalAdmixtureEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofChemicalAdmixtureEditField.AllowEmpty = 'on';
            app.MassofChemicalAdmixtureEditField.Editable = 'off';
            app.MassofChemicalAdmixtureEditField.Value = [];
            app.SpecificGravityofCementEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofCementEditFieldLabel.Text = 'Specific Gravity of Cement';
            app.SpecificGravityofCementEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofCementEditField.AllowEmpty = 'on';
            app.SpecificGravityofCementEditField.Value = [];
            app.SpecificGravityofWaterEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofWaterEditFieldLabel.Text = 'Specific Gravity of Water';
            app.SpecificGravityofWaterEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofWaterEditField.AllowEmpty = 'on';
            app.SpecificGravityofWaterEditField.Value = [];
            app.SpecificGravityofFlyashEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofFlyashEditFieldLabel.Text = 'Specific Gravity of Flyash';
            app.SpecificGravityofFlyashEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofFlyashEditField.AllowEmpty = 'on';
            app.SpecificGravityofFlyashEditField.Value = [];
            app.SpecificGravityofSilicaFumeEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofSilicaFumeEditFieldLabel.Text = 'Specific Gravity of Silica Fume';
            app.SpecificGravityofSilicaFumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofSilicaFumeEditField.AllowEmpty = 'on';
            app.SpecificGravityofSilicaFumeEditField.Value = [];
            app.SpecificGravityofGGBSEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofGGBSEditFieldLabel.Text = 'Specific Gravity of GGBS';
            app.SpecificGravityofGGBSEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofGGBSEditField.AllowEmpty = 'on';
            app.SpecificGravityofGGBSEditField.Value = [];
            app.SpecificGravityofMetakaolinEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofMetakaolinEditFieldLabel.Text = 'Specific Gravity of Metakaolin';
            app.SpecificGravityofMetakaolinEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofMetakaolinEditField.AllowEmpty = 'on';
            app.SpecificGravityofMetakaolinEditField.Value = [];
            app.SpecificGravityofChemicalAdmixtureEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofChemicalAdmixtureEditFieldLabel.Text = 'Specific Gravity of Chemical Admixture';
            app.SpecificGravityofChemicalAdmixtureEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofChemicalAdmixtureEditField.AllowEmpty = 'on';
            app.SpecificGravityofChemicalAdmixtureEditField.Value = [];
            app.MassofCementitiousMaterialLabel = uilabel(app.UIFigure);
            app.MassofCementitiousMaterialLabel.Text = ' Mass of Cementitious Material(%)';
            app.MassofCementitiousMaterialEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassofCementitiousMaterialEditField.AllowEmpty = 'on';
            app.MassofCementitiousMaterialEditField.Editable = 'off';
            app.MassofCementitiousMaterialEditField.Value = [];
            app.SpecificGravityofCoarseAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofCoarseAggregateEditFieldLabel.Text = 'Specific Gravity of Coarse Aggregate';
            app.SpecificGravityofCoarseAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofCoarseAggregateEditField.AllowEmpty = 'on';
            app.SpecificGravityofCoarseAggregateEditField.Value = [];
            app.SpecificGravityofFineAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificGravityofFineAggregateEditFieldLabel.Text = 'Specific Gravity of Fine Aggregate';
            app.SpecificGravityofFineAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificGravityofFineAggregateEditField.AllowEmpty = 'on';
            app.SpecificGravityofFineAggregateEditField.Value = [];
            app.CementContentEditFieldLabel = uilabel(app.UIFigure);
            app.CementContentEditFieldLabel.Text = 'Cement Content';
            app.CementContentEditField = uieditfield(app.UIFigure, 'numeric');
            app.CementContentEditField.AllowEmpty = 'on';
            app.CementContentEditField.Editable = 'off';
            app.CementContentEditField.Value = [];
            app.MIXCALCULATIONSBASEDONSSDCONDITIONLabel = uilabel(app.UIFigure);
            app.MIXCALCULATIONSBASEDONSSDCONDITIONLabel.Text = 'MIX CALCULATIONS BASED ON SSD CONDITION';
            app.FlyAshEditFieldLabel = uilabel(app.UIFigure);
            app.FlyAshEditFieldLabel.Text = 'Fly Ash';
            app.FlyAshEditField = uieditfield(app.UIFigure, 'numeric');
            app.FlyAshEditField.AllowEmpty = 'on';
            app.FlyAshEditField.Editable = 'off';
            app.FlyAshEditField.Value = [];
            app.SilicaFumeEditFieldLabel = uilabel(app.UIFigure);
            app.SilicaFumeEditFieldLabel.Text = 'Silica Fume';
            app.SilicaFumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SilicaFumeEditField.AllowEmpty = 'on';
            app.SilicaFumeEditField.Editable = 'off';
            app.SilicaFumeEditField.Value = [];
            app.GGBSEditFieldLabel = uilabel(app.UIFigure);
            app.GGBSEditFieldLabel.Text = 'GGBS';
            app.GGBSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GGBSEditField.AllowEmpty = 'on';
            app.GGBSEditField.Editable = 'off';
            app.GGBSEditField.Value = [];
            app.MetakaolinEditFieldLabel = uilabel(app.UIFigure);
            app.MetakaolinEditFieldLabel.Text = 'Metakaolin';
            app.MetakaolinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MetakaolinEditField.AllowEmpty = 'on';
            app.MetakaolinEditField.Editable = 'off';
            app.MetakaolinEditField.Value = [];
            app.WaterEditFieldLabel = uilabel(app.UIFigure);
            app.WaterEditFieldLabel.Text = 'Water';
            app.WaterEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaterEditField.AllowEmpty = 'on';
            app.WaterEditField.Editable = 'off';
            app.WaterEditField.Value = [];
            app.FineAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.FineAggregateEditFieldLabel.Text = 'Fine Aggregate';
            app.FineAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FineAggregateEditField.AllowEmpty = 'on';
            app.FineAggregateEditField.Editable = 'off';
            app.FineAggregateEditField.Value = [];
            app.CoarseAggregateEditFieldLabel = uilabel(app.UIFigure);
            app.CoarseAggregateEditFieldLabel.Text = 'Coarse Aggregate';
            app.CoarseAggregateEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoarseAggregateEditField.AllowEmpty = 'on';
            app.CoarseAggregateEditField.Editable = 'off';
            app.CoarseAggregateEditField.Value = [];
            app.ChemicalAdmixtureEditFieldLabel = uilabel(app.UIFigure);
            app.ChemicalAdmixtureEditFieldLabel.Text = 'Chemical Admixture';
            app.ChemicalAdmixtureEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChemicalAdmixtureEditField.AllowEmpty = 'on';
            app.ChemicalAdmixtureEditField.Editable = 'off';
            app.ChemicalAdmixtureEditField.Value = [];
            app.WCmEditFieldLabel = uilabel(app.UIFigure);
            app.WCmEditFieldLabel.Text = 'W/Cm';
            app.WCmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WCmEditField.AllowEmpty = 'on';
            app.WCmEditField.Editable = 'off';
            app.WCmEditField.Value = [];
            app.Kgm3Label = uilabel(app.UIFigure);
            app.Kgm3Label.Text = 'Kg/m3';
            app.Kgm3Label_2 = uilabel(app.UIFigure);
            app.Kgm3Label_2.Text = 'Kg/m3';
            app.Kgm3Label_3 = uilabel(app.UIFigure);
            app.Kgm3Label_3.Text = 'Kg/m3';
            app.Kgm3Label_4 = uilabel(app.UIFigure);
            app.Kgm3Label_4.Text = 'Kg/m3';
            app.Kgm3Label_5 = uilabel(app.UIFigure);
            app.Kgm3Label_5.Text = 'Kg/m3';
            app.Kgm3Label_6 = uilabel(app.UIFigure);
            app.Kgm3Label_6.Text = 'Kg/m3';
            app.Kgm3Label_7 = uilabel(app.UIFigure);
            app.Kgm3Label_7.Text = 'Kg/m3';
            app.Kgm3Label_8 = uilabel(app.UIFigure);
            app.Kgm3Label_8.Text = 'Kg/m3';
            app.Kgm3Label_9 = uilabel(app.UIFigure);
            app.Kgm3Label_9.Text = 'Kg/m3';
            app.Kgm3Label_10 = uilabel(app.UIFigure);
            app.Kgm3Label_10.Text = 'Kg/m3';
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note';
            app.DesignedasperIS102622019andotherISrelaventcodesLabel = uilabel(app.UIFigure);
            app.DesignedasperIS102622019andotherISrelaventcodesLabel.Text = '1. Designed as per IS 10262:2019 and other IS relavent codes';
            app.AggregatesareconsideredtobeinsaturatedsurfacedryconditionLabel = uilabel(app.UIFigure);
            app.AggregatesareconsideredtobeinsaturatedsurfacedryconditionLabel.Text = '3. Aggregates are considered to be in saturated surface dry condition';
            app.ValuesneedtobeenteredinthecolouredboxappropriatelyLabel = uilabel(app.UIFigure);
            app.ValuesneedtobeenteredinthecolouredboxappropriatelyLabel.Text = '4. Values need to be selected or entered in the blue coloured box appropriately';
            app.AuthorsLabel = uilabel(app.UIFigure);
            app.AuthorsLabel.Text = 'Authors :';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Mr.ANTONY RAJ T, Dr. JANE HELENA H, College of Engineering, Anna University, Chennai, 600 025';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2. Interface is limited to Grade M60, in upcoming version higher strength grades will be considered.';
            app.FINALLabel = uilabel(app.UIFigure);
            app.FINALLabel.Text = 'FINAL';
            app.Kgm3Label_11 = uilabel(app.UIFigure);
            app.Kgm3Label_11.Text = 'Kg/m3';
            app.Kgm3Label_12 = uilabel(app.UIFigure);
            app.Kgm3Label_12.Text = 'Kg/m3';
            app.ViscosityModifyingagentisnotconsideredinthisversionLabel = uilabel(app.UIFigure);
            app.ViscosityModifyingagentisnotconsideredinthisversionLabel.Text = '5. Viscosity Modifying agent is not considered in this version';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Feedback and suggestions are always welcome to improve this GUI Interface :';
            app.Kgm3Label_13 = uilabel(app.UIFigure);
            app.Kgm3Label_13.Text = 'Kg/m3';
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = 'mm';
            app.antonyrajengggmailcomLabel = uilabel(app.UIFigure);
            app.antonyrajengggmailcomLabel.Text = 'antonyrajengg@gmail.com';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MIX_PROPORTIONING_OF_CONCRETE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end